classdef StockAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RunAnalysisButton     matlab.ui.control.Button
        TextAreaSummary       matlab.ui.control.TextArea
        TextAreaSummaryLabel  matlab.ui.control.Label
        DatePicker2           matlab.ui.control.DatePicker
        DatePicker2Label      matlab.ui.control.Label
        ToDateLabel           matlab.ui.control.Label
        DatePicker            matlab.ui.control.DatePicker
        DatePickerLabel       matlab.ui.control.Label
        FromDateLabel         matlab.ui.control.Label
        DropDownTicker        matlab.ui.control.DropDown
        DropDownTickerLabel   matlab.ui.control.Label
        STOCKLabel            matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Initialize date pickers
        app.DatePicker.Value  = datetime('today') - calmonths(3);
        app.DatePicker2.Value = datetime('today');
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)

    % 1. Read inputs
    ticker   = app.DropDownTicker.Value;
    fromDate = app.DatePicker.Value;
    toDate   = app.DatePicker2.Value;

    % 2. Run analysis
    [forecastData, technicalData] = runStockAnalysis(ticker, fromDate, toDate);

    % 3. Plot price + forecast
    cla(app.UIAxes);
    plot(app.UIAxes, forecastData.allDates, forecastData.allPrices, 'b-', 'LineWidth',1.5);
    hold(app.UIAxes,'on');
    scatter(app.UIAxes, forecastData.allDates(end), forecastData.allPrices(end),70,'filled');
    scatter(app.UIAxes, forecastData.nextDate,    forecastData.forecastValue,70,'filled');
    hold(app.UIAxes,'off');
    title(app.UIAxes, sprintf('%s Forecast (%s)', ticker, forecastData.analysisType));
    xlabel(app.UIAxes,'Date'); ylabel(app.UIAxes,'Price ($)');
    legend(app.UIAxes,'Historical','Last','Forecast','Location','best');
    grid(app.UIAxes,'on');

    % 4. Plot technical indicators
    cla(app.UIAxes2);
    yyaxis(app.UIAxes2,'left');
    plot(app.UIAxes2, forecastData.allDates, technicalData.sma20,'r--','LineWidth',1.2);
    hold(app.UIAxes2,'on');
    plot(app.UIAxes2, forecastData.allDates, technicalData.sma50,'k--','LineWidth',1.2);
    hold(app.UIAxes2,'off');
    ylabel(app.UIAxes2,'SMA');
    yyaxis(app.UIAxes2,'right');
    plot(app.UIAxes2, forecastData.allDates, technicalData.rsiValues,'g-','LineWidth',1.2);
    ylabel(app.UIAxes2,'RSI');
    title(app.UIAxes2,'Technical Indicators');
    xlabel(app.UIAxes2,'Date'); grid(app.UIAxes2,'on');
    legend(app.UIAxes2,'SMA20','SMA50','RSI','Location','best');

    % 5. Update summary text
    app.TextAreaSummary.FontSize            = 14;
    app.TextAreaSummary.FontWeight          = 'bold';
    app.TextAreaSummary.HorizontalAlignment = 'left';
    app.TextAreaSummary.Value = {
        sprintf('Next Day: %s', datestr(forecastData.nextDate,'dd-mmm-yyyy'));
        sprintf('Forecast (%s): $%.2f', forecastData.chosenModel, forecastData.forecastValue);
        '';
        'RSI (14-day):';
        sprintf('  Latest RSI: %.2f', technicalData.lastRSI);
        sprintf('  Recommendation: %s', technicalData.recommendation);
        '';
        'DISCLAIMER: For educational purposes only.';
        'Do your own research before investing.'
    };
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxesPrice')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'UIAxesTech')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.STOCKLabel = uilabel(app.UIFigure);
            app.STOCKLabel.Text = 'STOCK';
            app.DropDownTickerLabel = uilabel(app.UIFigure);
            app.DropDownTickerLabel.Text = 'Drop DownTicker';
            app.DropDownTicker = uidropdown(app.UIFigure);
            app.DropDownTicker.Items = {'APPLE', 'BOEING', 'CISCO', 'AMAZON', 'COCO', 'DISNEY', 'GOOGLE', 'IBM', 'INTEL', 'NETFLIX'};
            app.DropDownTicker.Value = 'APPLE';
            app.FromDateLabel = uilabel(app.UIFigure);
            app.FromDateLabel.Text = 'From Date';
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.ToDateLabel = uilabel(app.UIFigure);
            app.ToDateLabel.Text = 'To Date';
            app.DatePicker2Label = uilabel(app.UIFigure);
            app.DatePicker2Label.Text = 'Date Picker2';
            app.DatePicker2 = uidatepicker(app.UIFigure);
            app.TextAreaSummaryLabel = uilabel(app.UIFigure);
            app.TextAreaSummaryLabel.Text = {'Text Area'; 'Summary'};
            app.TextAreaSummary = uitextarea(app.UIFigure);
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StockAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end